classdef AdaptivePurePursuitApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        AdaptiveCurveTrackingRobotusingPurePursuitLabel  matlab.ui.control.Label
        PathLabel              matlab.ui.control.Label
        SpeedLabel             matlab.ui.control.Label
        StartSimulationButton  matlab.ui.control.Button
        PathDropDown           matlab.ui.control.DropDown
        PathDropDownLabel      matlab.ui.control.Label
        UIAxes                 matlab.ui.control.UIAxes
        SimulationAxes         matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartSimulationButton
        function StartSimulationButtonPushed(app, event)


    selectedPath = app.PathDropDown.Value;
    pathType = lower(strtrim(string(selectedPath)));
    
    % Handle possible spelling variation from the dropdown
    if pathType == "sinosudial"
        pathType = "sinosoidal";
    end
    
    % Generate the selected path
    [x_track, y_track] = generatePath(pathType);

    % Controller settings
    lookAhead = 20;
    Kp = 0.08;

    % Speed settings
    minSpeed = 0.8;
    maxSpeed = 3;

    % Simulation settings
    dt = 0.15;
    numSteps = 2500;

    % Circle is a closed path
    isClosed = strcmpi(pathType, "circle");

    % Initial robot position
    if pathType == "circle"
        x = 400;
        y = 200;
        theta = pi/2;
    else
        x = 0;
        y = 200;
        theta = 0;
    end

    % Update information labels
    app.PathLabel.Text = ['Path: ' char(selectedPath)];
    app.SpeedLabel.Text = 'Speed: 0';

    % Clear simulation axes
    cla(app.SimulationAxes);

    % Plot reference path
    plot(app.SimulationAxes, ...
        x_track, y_track, ...
        'b', 'LineWidth', 2);

    hold(app.SimulationAxes, 'on');

    % Plot robot
    robotPlot = plot(app.SimulationAxes, ...
        x, y, ...
        'ro', ...
        'MarkerSize', 8, ...
        'MarkerFaceColor', 'r');

    % Plot look-ahead target
    targetPlot = plot(app.SimulationAxes, ...
        x, y, ...
        'gx', ...
        'MarkerSize', 10, ...
        'LineWidth', 2);

    % Plot robot trajectory
    trajectoryPlot = plot(app.SimulationAxes, ...
        x, y, ...
        'r--');

    % Format simulation axes
    grid(app.SimulationAxes, 'on');
    axis(app.SimulationAxes, 'equal');

    xlabel(app.SimulationAxes, 'X');
    ylabel(app.SimulationAxes, 'Y');

    title(app.SimulationAxes, ...
        ['Adaptive Pure Pursuit - ' char(selectedPath)]);

    % Store simulation data
    timeData = zeros(1, numSteps);
    speedData = zeros(1, numSteps);

    robotX = zeros(1, numSteps);
    robotY = zeros(1, numSteps);

    % =========================
    % SIMULATION LOOP
    % =========================

    for step = 1:numSteps

        % Pure Pursuit controller
        [theta, ~, targetX, targetY, nearestIdx] = ...
            purePursuit( ...
            x, y, theta, ...
            x_track, y_track, ...
            lookAhead, Kp, isClosed);
        % Stop when an open path reaches its end
        if ~isClosed && nearestIdx >= length(x_track) - lookAhead
            break;
        end

        % Calculate adaptive speed
        speed = adaptiveSpeed( ...
            x_track, y_track, ...
            nearestIdx, ...
            minSpeed, maxSpeed);

        % Move robot
        [x, y] = robotModel( ...
            x, y, theta, ...
            speed, dt);

        % Store data
        timeData(step) = step * dt;
        speedData(step) = speed;

        robotX(step) = x;
        robotY(step) = y;

        % Update robot position
        set(robotPlot, ...
            'XData', x, ...
            'YData', y);

        % Update target position
        set(targetPlot, ...
            'XData', targetX, ...
            'YData', targetY);

        % Update robot trajectory
        set(trajectoryPlot, ...
            'XData', robotX(1:step), ...
            'YData', robotY(1:step));

        % Update speed display
        app.SpeedLabel.Text = ...
            sprintf('Speed: %.2f', speed);

        % Refresh GUI
        drawnow;

        pause(0.002);

    end

    % =========================
    % SPEED VS TIME GRAPH
    % =========================

    cla(app.UIAxes);

    plot(app.UIAxes, ...
        timeData(1:step), speedData(1:step), ...
        'LineWidth', 2);

    grid(app.UIAxes, 'on');

    xlabel(app.UIAxes, 'Time (seconds)');
    ylabel(app.UIAxes, 'Robot Speed (units/s)');
    title(app.UIAxes, 'Robot Speed Variation with Time');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SimulationAxes = uiaxes(app.UIFigure);
            title(app.SimulationAxes, 'Adaptive Pure Pursuit ')
            xlabel(app.SimulationAxes, 'X - position')
            ylabel(app.SimulationAxes, 'Y - position')
            zlabel(app.SimulationAxes, 'Z')
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Robot Speed Variation with Time')
            xlabel(app.UIAxes, 'Time (seconds)')
            ylabel(app.UIAxes, 'Robot Speed (units/s)')
            zlabel(app.UIAxes, 'Z')
            app.PathDropDownLabel = uilabel(app.UIFigure);
            app.PathDropDownLabel.Text = 'Path';
            app.PathDropDown = uidropdown(app.UIFigure);
            app.PathDropDown.Items = {'Straight', 'Circle', 'Sinosoidal', 'S-shaped'};
            app.PathDropDown.Value = 'Straight';
            app.StartSimulationButton = uibutton(app.UIFigure, 'push');
            app.StartSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @StartSimulationButtonPushed, true);
            app.StartSimulationButton.Text = 'Start Simulation';
            app.SpeedLabel = uilabel(app.UIFigure);
            app.SpeedLabel.Text = 'Speed: 0';
            app.PathLabel = uilabel(app.UIFigure);
            app.PathLabel.Text = 'Path: None';
            app.AdaptiveCurveTrackingRobotusingPurePursuitLabel = uilabel(app.UIFigure);
            app.AdaptiveCurveTrackingRobotusingPurePursuitLabel.Text = 'Adaptive Curve Tracking Robot using Pure Pursuit';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AdaptivePurePursuitApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end